classdef MainMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EditPaddle2VelocityEditField  matlab.ui.control.NumericEditField
        EditPaddle2VelocityEditFieldLabel  matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        SliderLabel                   matlab.ui.control.Label
        PlayPongButton                matlab.ui.control.Button
        StartPlotsButton              matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
         % Description
            datapassed=[]
            datatime=[]
            NumChannels=1; %maybe may be better to get from RHX.. but unsure if it will be accurate
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            disp('Started!')
            RHXStreamRealtimecopy

        end

        % Button pushed function: StartPlotsButton
        function Buttonpushed(app, event)
             %data from RHXStreamRealtimecopy
             app.datapassed=getappdata(0,'evalue');
             app.datatime=getappdata(1,'timevalue');
             axesSpec=app.UIAxes2;
             axesSignal=app.UIAxes;
  
             for i=1:app.NumChannels
                plot(axesSignal,app.datatime, app.datapassed(i,:),'-r')
                hold on
             end
             
             
             %Spectrogram
             [s,f,t]=spectrogram(app.datapassed(app.NumChannels,:)); %prob need a for loop, but we may not need this anyways
             imagesc(axesSpec,f,t,log(abs(s')))
             
            
        end

        % Button pushed function: PlayPongButton
        function Playpong(app, event)
            pong
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            xlim(app.UIAxes2,[0, value])
        end

        % Value changed function: EditPaddle2VelocityEditField
        function EditPaddle2VelocityEditFieldValueChanged(app, event)
            value = app.EditPaddle2VelocityEditField.Value;
%             if value > 0
%                 setappdata(2,'velocity',value);
%             else
%                 fprintf('Please use positive values :)')
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Frequency')
            zlabel(app.UIAxes2, 'Z')
            app.StartPlotsButton = uibutton(app.UIFigure, 'push');
            app.StartPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @Buttonpushed, true);
            app.StartPlotsButton.Text = 'Start Plots';
            app.PlayPongButton = uibutton(app.UIFigure, 'push');
            app.PlayPongButton.ButtonPushedFcn = createCallbackFcn(app, @Playpong, true);
            app.PlayPongButton.Text = 'Play Pong';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 5];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.EditPaddle2VelocityEditFieldLabel = uilabel(app.UIFigure);
            app.EditPaddle2VelocityEditFieldLabel.Text = 'Edit Paddle 2Velocity';
            app.EditPaddle2VelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditPaddle2VelocityEditField.ValueChangedFcn = createCallbackFcn(app, @EditPaddle2VelocityEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainMenu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end